classdef DBSCANClusteringApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        FileNameEditField         matlab.ui.control.EditField
        FileNameEditFieldLabel    matlab.ui.control.Label
        ImportButton_2            matlab.ui.control.StateButton
        ImportDataLabel           matlab.ui.control.Label
        ParametersDBSCANLabel     matlab.ui.control.Label
        EpsilonSlider             matlab.ui.control.Slider
        EpsilonLabel              matlab.ui.control.Label
        MinPointsSpinner          matlab.ui.control.Spinner
        MinnumberofpointsLabel    matlab.ui.control.Label
        DBSCANClusteringAppLabel  matlab.ui.control.Label
        UIAxes                    matlab.ui.control.UIAxes
    end

    
     properties (Access = public)
        ImportedData double % Store imported data (X and Y)
    end
    
    methods (Access = public)
        
        function performDBSCAN(app)
            % Get parameters
            epsilon = app.EpsilonSlider.Value;
            minPts = app.MinPointsSpinner.Value;
            
            % Perform DBSCAN clustering
            labels = dbscan(app.ImportedData, epsilon, minPts);
            
            % Plot the clustered data
            cla(app.UIAxes);
            gscatter(app.UIAxes, app.ImportedData(:, 1), app.ImportedData(:, 2), labels);
            title(app.UIAxes, 'DBSCAN Clustering');
            xlabel(app.UIAxes, 'X');
            ylabel(app.UIAxes, 'Y');
        end
        
         % Function to update the plot
        function updatePlot(app)
            % Clear the axes
            cla(app.UIAxes);
            
            % Check if data is available
            if isempty(app.ImportedData)
                return;
            end
            
            % Plot the imported data
            scatter(app.UIAxes, app.ImportedData(:, 1), app.ImportedData(:, 2), 'filled');
            title(app.UIAxes, 'Imported Data');
            xlabel(app.UIAxes, 'X');
            ylabel(app.UIAxes, 'Y');
        end
    end
  

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: ImportButton_2
        function ImportButton_2ValueChanged(app, event)
            try
                % Open a file dialog to select a CSV file
                [file, path] = uigetfile('*.csv', 'Select a CSV File');
                if isequal(file, 0)
                    return; % User canceled
                end
            
                % Update the FileNameEditField with the selected file name
                app.FileNameEditField.Value = file;
            
                % Read the CSV file
                fullPath = fullfile(path, file);
                data = readmatrix(fullPath);
            
                % Ensure there are at least two columns
                if size(data, 2) < 2
                    uialert(app.UIFigure, 'The file must have at least 2 columns.', 'Error');
                    return;
                end
            
                % Store the imported data in the app property
                app.ImportedData = data(:, 1:2); % Use the first two columns
            
                % Notify the user
                uialert(app.UIFigure, 'Data imported successfully.', 'Success');
            
                % Update the plot with the new data
                app.updatePlot();
            
            catch ME
                % Handle unexpected errors
                uialert(app.UIFigure, sprintf('Error: %s', ME.message), 'Error');
            end
        end

        % Value changed function: EpsilonSlider
        function EpsilonSliderValueChanged(app, event)
             % Perform DBSCAN clustering when Epsilon changes
            if ~isempty(app.ImportedData)
                app.performDBSCAN();
            end
            
        end

        % Value changed function: MinPointsSpinner
        function MinPointsSpinnerValueChanged(app, event)
            % Perform DBSCAN clustering when MinPoints changes
            if ~isempty(app.ImportedData)
                app.performDBSCAN();
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Clustred Data')
            app.DBSCANClusteringAppLabel = uilabel(app.UIFigure);
            app.DBSCANClusteringAppLabel.Text = 'DBSCAN Clustering App';
            app.MinnumberofpointsLabel = uilabel(app.UIFigure);
            app.MinnumberofpointsLabel.Text = 'Min number of points: ';
            app.MinPointsSpinner = uispinner(app.UIFigure);
            app.MinPointsSpinner.Limits = [1 Inf];
            app.MinPointsSpinner.ValueChangedFcn = createCallbackFcn(app, @MinPointsSpinnerValueChanged, true);
            app.MinPointsSpinner.Value = 5;
            app.EpsilonLabel = uilabel(app.UIFigure);
            app.EpsilonLabel.Text = 'Epsilon:';
            app.EpsilonSlider = uislider(app.UIFigure);
            app.EpsilonSlider.Limits = [0 2];
            app.EpsilonSlider.ValueChangedFcn = createCallbackFcn(app, @EpsilonSliderValueChanged, true);
            app.EpsilonSlider.Value = 0.4;
            app.ParametersDBSCANLabel = uilabel(app.UIFigure);
            app.ParametersDBSCANLabel.Text = 'Parameters DBSCAN:';
            app.ImportDataLabel = uilabel(app.UIFigure);
            app.ImportDataLabel.Text = 'Import Data';
            app.ImportButton_2 = uibutton(app.UIFigure, 'state');
            app.ImportButton_2.ValueChangedFcn = createCallbackFcn(app, @ImportButton_2ValueChanged, true);
            app.ImportButton_2.Text = 'Import';
            app.FileNameEditFieldLabel = uilabel(app.UIFigure);
            app.FileNameEditFieldLabel.Text = 'File Name';
            app.FileNameEditField = uieditfield(app.UIFigure, 'text');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = DBSCANClusteringApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end