classdef Kmeansapp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        StartButton               matlab.ui.control.Button
        GenerateButton            matlab.ui.control.Button
        GenearteCentersLabel      matlab.ui.control.Label
        KnumberofclusterSpinner   matlab.ui.control.Spinner
        KnumberofclusterLabel     matlab.ui.control.Label
        ParametersKMeansLabel     matlab.ui.control.Label
        ImportButton              matlab.ui.control.Button
        FileNameEditField         matlab.ui.control.EditField
        FileNameEditFieldLabel    matlab.ui.control.Label
        ImportDataLabel           matlab.ui.control.Label
        KMeansClusteringAppLabel  matlab.ui.control.Label
        UIAxes                    matlab.ui.control.UIAxes
    end

    
     properties (Access = public)
        data double
        initialCenters double
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: ImportButton
        function ImportButtonPushed(app, event)
            [file, path] = uigetfile({'*.csv', 'CSV Files (*.csv)'});
    if isequal(file, 0)
        disp('User selected Cancel');
    else
        app.FileNameEditField.Value = file; % Display only the file name
        fullFilePath = fullfile(path, file);
        
        % Load data from the CSV file
        app.data = readmatrix(fullFilePath);
        
        % Plot the imported data
        scatter(app.UIAxes, app.data(:,1), app.data(:,2), 'b');
        title(app.UIAxes, ['Imported Data: ', file]);
        xlabel(app.UIAxes, 'Feature 1');
        ylabel(app.UIAxes, 'Feature 2');
    end
        end

        % Button pushed function: GenerateButton
        function GenerateButtonPushed(app, event)
            if isempty(app.data)
                uialert(app.UIFigure, 'Please import data first.', 'No Data');
                return;
            end
            k = app.KnumberofclusterSpinner.Value;
        
            % Randomly select k points from the data to initialize centers
            idx = randperm(size(app.data, 1), k);
            app.initialCenters = app.data(idx, :);
        
            % Clear previous plot and plot new data points
            cla(app.UIAxes); % Clear the axes
            scatter(app.UIAxes, app.data(:,1), app.data(:,2), 'b'); % Plot data points
            hold(app.UIAxes, 'on');
        
            % Plot the new initial centers
            plot(app.UIAxes, app.initialCenters(:,1), app.initialCenters(:,2), 'r*', 'MarkerSize', 10, 'LineWidth', 2);
            legend(app.UIAxes, 'Data Points', 'Initial Centers');
            hold(app.UIAxes, 'off');
        
            title(app.UIAxes, 'Data with New Initial Centers');
        end

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
            k = app.KnumberofclusterSpinner.Value;
            [idx, C] = kmeans(app.data, k, 'Start', app.initialCenters);
            gscatter(app.UIAxes, app.data(:,1), app.data(:,2), idx);
            hold(app.UIAxes, 'on');
            plot(app.UIAxes, C(:,1), C(:,2), 'kx', 'MarkerSize', 15, 'LineWidth', 3);
            hold(app.UIAxes, 'off');
            title(app.UIAxes, 'K-means Clustering Results');

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Clustred dara')
            app.KMeansClusteringAppLabel = uilabel(app.UIFigure);
            app.KMeansClusteringAppLabel.Text = 'KMeans Clustering App';
            app.ImportDataLabel = uilabel(app.UIFigure);
            app.ImportDataLabel.Text = 'Import Data';
            app.FileNameEditFieldLabel = uilabel(app.UIFigure);
            app.FileNameEditFieldLabel.Text = 'File Name';
            app.FileNameEditField = uieditfield(app.UIFigure, 'text');
            app.ImportButton = uibutton(app.UIFigure, 'push');
            app.ImportButton.ButtonPushedFcn = createCallbackFcn(app, @ImportButtonPushed, true);
            app.ImportButton.Text = 'Import';
            app.ParametersKMeansLabel = uilabel(app.UIFigure);
            app.ParametersKMeansLabel.Text = 'Parameters KMeans';
            app.KnumberofclusterLabel = uilabel(app.UIFigure);
            app.KnumberofclusterLabel.Text = 'K (number of cluster)';
            app.KnumberofclusterSpinner = uispinner(app.UIFigure);
            app.GenearteCentersLabel = uilabel(app.UIFigure);
            app.GenearteCentersLabel.Text = 'Genearte Centers';
            app.GenerateButton = uibutton(app.UIFigure, 'push');
            app.GenerateButton.ButtonPushedFcn = createCallbackFcn(app, @GenerateButtonPushed, true);
            app.GenerateButton.Text = 'Generate';
            app.StartButton = uibutton(app.UIFigure, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Kmeansapp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end