classdef application < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        DataPanel                  matlab.ui.container.Panel
        SaveButton                 matlab.ui.control.Button
        SavegenerateddatacsvLabel  matlab.ui.control.Label
        ClusterstdSlider_2         matlab.ui.control.Slider
        ClusterstdSlider_2Label    matlab.ui.control.Label
        NoiseSlider                matlab.ui.control.Slider
        ClusterstdLabel            matlab.ui.control.Label
        NumberofcenterSpinner      matlab.ui.control.Spinner
        NumberofcenterLabel        matlab.ui.control.Label
        NumberofPointsSpinner      matlab.ui.control.Spinner
        GenerateLabel              matlab.ui.control.Label
        NumberofPointsLabel        matlab.ui.control.Label
        TypeButtonGroup            matlab.ui.container.ButtonGroup
        GaussianButton             matlab.ui.control.RadioButton
        CircleButton               matlab.ui.control.RadioButton
        GenerateButton             matlab.ui.control.Button
        ImportButton               matlab.ui.control.StateButton
        FileNameEditField          matlab.ui.control.EditField
        FileNameEditFieldLabel     matlab.ui.control.Label
        UploadLabel                matlab.ui.control.Label
        UIAxes                     matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        GeneratedData double % Store uploaded/generated data (X and Y)
        Labels double % Store labels for the data
    end
% Local functions for data generation
methods (Access = private)
    function [data, labels] = make_blobs(app, n_samples, centers, cluster_std)
        % Generate data with Gaussian blobs.

        % If centers is a scalar, generate random centers
        if isscalar(centers)
            centers = rand(centers, 2) * 10; % Random centers in [0, 10]
        end

        % Generate data
        n_centers = size(centers, 1);
        samples_per_center = floor(n_samples / n_centers);
        data = [];
        labels = [];

        for i = 1:n_centers
            center_data = randn(samples_per_center, 2) * cluster_std + centers(i, :);
            data = [data; center_data];
            labels = [labels; i * ones(samples_per_center, 1)];
        end

        % Add remaining samples if n_samples is not divisible by n_centers
        if size(data, 1) < n_samples
            remaining_samples = n_samples - size(data, 1);
            center_data = randn(remaining_samples, 2) * cluster_std + centers(end, :);
            data = [data; center_data];
            labels = [labels; n_centers * ones(remaining_samples, 1)];
        end
    end

    function [data, labels] = make_circle(app, n_samples, noise)
    
        % Generate angles for the circle
        angles = linspace(0, 2*pi, n_samples)';
    
        % Outer circle (radius = 1)
        outer_circle = [cos(angles), sin(angles)];
    
        % Inner circle (radius = 0.5, fixed)
        inner_circle = 0.5 * [cos(angles), sin(angles)];
    
        % Combine data
        data = [outer_circle; inner_circle];
    
        % Add Gaussian noise
        data = data + noise * randn(size(data));
    
        % Create labels (0 for outer circle, 1 for inner circle)
        labels = [zeros(n_samples, 1); ones(n_samples, 1)];
    end
end

    % Callbacks that handle component events
    methods (Access = private)

        % Selection changed function: TypeButtonGroup
        function TypeButtonGroupSelectionChanged(app, event)
            % Get the selected radio button
            selectedButton = app.TypeButtonGroup.SelectedObject;
        
            % Check which radio button is selected
            if selectedButton == app.GaussianButton
                % Gaussian is selected
                % Show Gaussian-specific components
                app.NumberofcenterSpinner.Visible = 'on';
                app.NumberofcenterLabel.Visible = 'on';
                app.ClusterstdSlider_2.Visible = 'on';
                app.ClusterstdSlider_2Label.Visible = 'on';
        
                % Hide Circle-specific components
                app.NoiseSlider.Visible = 'off';
                app.ClusterstdLabel.Visible = 'off';
        
            elseif selectedButton == app.CircleButton
                % Circle is selected
                % Show Circle-specific components
                app.NoiseSlider.Visible = 'on';
                app.ClusterstdLabel.Visible = 'on';
        
                % Hide Gaussian-specific components
                app.NumberofcenterSpinner.Visible = 'off';
                app.NumberofcenterLabel.Visible = 'off';
                app.ClusterstdSlider_2.Visible = 'off';
                app.ClusterstdSlider_2Label.Visible = 'off';
            end
        end

        % Button pushed function: GenerateButton
        function GenerateButtonPushed(app, event)
            % Get the number of points
            n_samples = app.NumberofPointsSpinner.Value;
        
            % Check which data type is selected
            if app.GaussianButton.Value
                % Generate Gaussian data
                centers = app.NumberofcenterSpinner.Value; % Number of centers
                cluster_std = app.ClusterstdSlider_2.Value; % Cluster standard deviation
                [app.GeneratedData, app.Labels] = app.make_blobs(n_samples, centers, cluster_std);
        
            elseif app.CircleButton.Value
                % Generate Circle data
                noise = app.NoiseSlider.Value; % Noise level
                [app.GeneratedData, app.Labels] = app.make_circle(n_samples, noise); % No factor parameter
            end
        
            % Plot the generated data
            scatter(app.UIAxes, app.GeneratedData(:, 1), app.GeneratedData(:, 2), [], app.Labels, 'filled');
            title(app.UIAxes, 'Generated Data');
            xlabel(app.UIAxes, 'X');
            ylabel(app.UIAxes, 'Y');
            colorbar(app.UIAxes);

        end

        % Value changed function: ImportButton
        function ImportButtonValueChanged(app, event)
            try
                % Open file dialog to select a file
                [file, path] = uigetfile({'*.csv;*.mat;*.xlsx', 'Supported Files (*.csv, *.mat, *.xlsx)'}, 'Select a file');
            
                % Check if the user canceled the file selection
                if isequal(file, 0)
                    return; % User canceled, do nothing
                end
            
                % Update the file name in the EditField
                app.FileNameEditField.Value = file;
            
                % Load the data from the file
                fullPath = fullfile(path, file);
                if endsWith(file, '.csv')
                    % Read CSV file
                    data = readtable(fullPath);
                elseif endsWith(file, '.mat')
                    % Read MAT file
                    data = load(fullPath);
                    data = struct2array(data); % Convert struct to array
                elseif endsWith(file, '.xlsx')
                    % Read Excel file
                    data = readtable(fullPath);
                else
                    uialert(app.UIFigure, 'Unsupported file format.', 'Error');
                    return;
                end
            
                % Convert table to array if necessary
                if istable(data)
                    data = table2array(data);
                end
            
                % Check if the data has at least 2 columns (X and Y)
                if size(data, 2) < 2
                    uialert(app.UIFigure, 'The file must contain at least 2 columns (X and Y).', 'Error');
                    return;
                end
            
                % Check if the data has more than 3 columns (X, Y, and optional Label)
                if size(data, 2) > 3
                    uialert(app.UIFigure, 'The file contains more than 3 columns. Only the first 3 columns (X, Y, Label) will be used.', 'Warning');
                end
            
                % Assume the first two columns are X and Y, and the third column is labels (if available)
                app.GeneratedData = data(:, 1:2); % Store X and Y data
                if size(data, 2) >= 3
                    app.Labels = data(:, 3); % Store labels if available
                else
                    app.Labels = ones(size(data, 1), 1); % Default labels if not provided
                end
            
                % Plot the uploaded data
                scatter(app.UIAxes, app.GeneratedData(:, 1), app.GeneratedData(:, 2), [], app.Labels, 'filled');
                title(app.UIAxes, 'Uploaded Data');
                xlabel(app.UIAxes, 'X');
                ylabel(app.UIAxes, 'Y');
                colorbar(app.UIAxes);
            
            catch ME
                % Handle any unexpected errors
                uialert(app.UIFigure, sprintf('An error occurred: %s', ME.message), 'Error');
            end
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            % Check if there is generated data to save
            if isempty(app.GeneratedData)
                uialert(app.UIFigure, 'No data available to save.', 'Error');
                return;
            end
        
            % Open a file dialog to specify the save location
            [file, path] = uiputfile('*.csv', 'Save Generated Data As');
            
            % Check if the user canceled the save operation
            if isequal(file, 0)
                return; % User canceled, do nothing
            end
        
            % Combine the data and labels into a table
            dataToSave = array2table([app.GeneratedData, app.Labels], ...
                'VariableNames', {'X', 'Y', 'Label'});
        
            % Save the table to a CSV file
            fullPath = fullfile(path, file);
            writetable(dataToSave, fullPath);
        
            % Notify the user that the data has been saved
            uialert(app.UIFigure, sprintf('Data saved successfully to:\n%s', fullPath), 'Success');
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.DataPanel = uipanel(app.UIFigure);
            app.DataPanel.Title = 'Data';
            app.UIAxes = uiaxes(app.DataPanel);
            title(app.UIAxes, 'Generated Data')
            app.UploadLabel = uilabel(app.DataPanel);
            app.UploadLabel.Text = 'Upload';
            app.FileNameEditFieldLabel = uilabel(app.DataPanel);
            app.FileNameEditFieldLabel.Text = 'File Name';
            app.FileNameEditField = uieditfield(app.DataPanel, 'text');
            app.ImportButton = uibutton(app.DataPanel, 'state');
            app.ImportButton.ValueChangedFcn = createCallbackFcn(app, @ImportButtonValueChanged, true);
            app.ImportButton.Text = 'Import';
            app.GenerateButton = uibutton(app.DataPanel, 'push');
            app.GenerateButton.ButtonPushedFcn = createCallbackFcn(app, @GenerateButtonPushed, true);
            app.GenerateButton.Text = 'Generate';
            app.TypeButtonGroup = uibuttongroup(app.DataPanel);
            app.TypeButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @TypeButtonGroupSelectionChanged, true);
            app.TypeButtonGroup.Title = 'Type';
            app.CircleButton = uiradiobutton(app.TypeButtonGroup);
            app.CircleButton.Text = 'Circle';
            app.CircleButton.Value = true;
            app.GaussianButton = uiradiobutton(app.TypeButtonGroup);
            app.GaussianButton.Text = 'Gaussian';
            app.NumberofPointsLabel = uilabel(app.DataPanel);
            app.NumberofPointsLabel.Text = 'Number of Points:';
            app.GenerateLabel = uilabel(app.DataPanel);
            app.GenerateLabel.Text = 'Generate';
            app.NumberofPointsSpinner = uispinner(app.DataPanel);
            app.NumberofPointsSpinner.Limits = [0 1000];
            app.NumberofPointsSpinner.Value = 50;
            app.NumberofcenterLabel = uilabel(app.DataPanel);
            app.NumberofcenterLabel.Visible = 'off';
            app.NumberofcenterLabel.Text = 'Number of center: ';
            app.NumberofcenterSpinner = uispinner(app.DataPanel);
            app.NumberofcenterSpinner.Limits = [1 10];
            app.NumberofcenterSpinner.Visible = 'off';
            app.NumberofcenterSpinner.Value = 2;
            app.ClusterstdLabel = uilabel(app.DataPanel);
            app.ClusterstdLabel.Text = 'Noise: ';
            app.NoiseSlider = uislider(app.DataPanel);
            app.NoiseSlider.Limits = [0 0.5];
            app.NoiseSlider.Value = 0.2;
            app.ClusterstdSlider_2Label = uilabel(app.DataPanel);
            app.ClusterstdSlider_2Label.Visible = 'off';
            app.ClusterstdSlider_2Label.Text = 'Cluster std: ';
            app.ClusterstdSlider_2 = uislider(app.DataPanel);
            app.ClusterstdSlider_2.Limits = [0 2];
            app.ClusterstdSlider_2.Visible = 'off';
            app.ClusterstdSlider_2.Value = 0.5;
            app.SavegenerateddatacsvLabel = uilabel(app.DataPanel);
            app.SavegenerateddatacsvLabel.Text = 'Save generated data (.csv)';
            app.SaveButton = uibutton(app.DataPanel, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Text = 'Save';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = application

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end